classdef ee415_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImageButton                    matlab.ui.control.Button
        AnimationButton                matlab.ui.control.Button
        BackProjectionPanel            matlab.ui.container.Panel
        CalculateBackProjectionButton  matlab.ui.control.Button
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        ImageSizeEditField             matlab.ui.control.NumericEditField
        ImageSizeEditFieldLabel        matlab.ui.control.Label
        SaveButton_2                   matlab.ui.control.Button
        LoadButton_2                   matlab.ui.control.Button
        ProjectionPanel                matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        CalculateProjectionButton      matlab.ui.control.Button
        NumberofAnlgesEditField        matlab.ui.control.NumericEditField
        NumberofAnlgesEditFieldLabel   matlab.ui.control.Label
        NumberofProjectionsEditField   matlab.ui.control.NumericEditField
        NumberofProjectionsEditFieldLabel  matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        MustafaErdiKararmaz2375194Label  matlab.ui.control.Label
        EE415TermProjectLabel          matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image_data % Description
        project_data
        bproject_data
    end
    
    methods (Access = private)
        
        function projection_data = projection(app,image,num_projections, num_sampling_points)
            
           
    %memory allocation
    projection_data = zeros(num_projections, num_sampling_points);

    % Define the angles for projection.
    angles = linspace(0,180-180/num_projections,num_projections);
    
    %if one of the dimensions is odd number
    oddnumberpixel = 0;
    if(mod(size(image,1),2)==1 || mod(size(image,2),2)==1)
        B = ones(2);
        image = kron(image,B);
        oddnumberpixel = 1;
    end
    % define the image size
    
    M = max(size(image));
    
    %define t line
    t = linspace(-M*sqrt(2)/2, M*sqrt(2)/2, num_sampling_points);
    x_val = [-size(image,1)/2:size(image,1)/2]';
    y_val = [-size(image,2)/2:size(image,2)/2]';
    
    for i = 1:num_projections
        theta = angles(i);
        projection = zeros(num_sampling_points,1);
    
        for j = 1:num_sampling_points
            
           
           %calculate the corresponding x and y values of the grid
           corr_x_val = (t(j)-y_val*cosd(theta))/sind(theta);
           corr_y_val = (t(j)-x_val*sind(theta))/cosd(theta);
           
           %concatanate the x and y values
           coords = [x_val corr_y_val ; corr_x_val y_val];
           count = 1;
           coords_new = zeros(size(coords,1),2);
           
           %remove outside points
           for ind = 1:size(coords,1)
            if(abs(coords(ind,1))<=size(image,1)/2 && abs(coords(ind,2))<=size(image,2)/2)
                coords_new(count,:) = [coords(ind,1) coords(ind,2)];
                count = count +1;
            end
           end
           coords_new = coords_new(1:count-1,:);
           % if all of the points are outside of the image, we can continue
           % to calculate the other t values.
           if(isempty(coords_new))
            projection(j) = 0;
            continue;
           end
           
           %sort the new coordinates
           coords_new = sortrows(coords_new,1); 
           
           %finding the distances
           dists = zeros(size(coords_new,1)-1,1);
           for indd = 1:size(dists,1)            
               dists(indd) = sqrt((coords_new(indd+1,1)-coords_new(indd,1))^2+(coords_new(indd+1,2)-coords_new(indd,2))^2);             
           end
           
           %finding the midpoints
           midpoints = zeros(size(coords_new,1)-1,2);   
           for indd = 1:size(midpoints,1)           
               midpoints(indd,:) = [(coords_new(indd+1,1)+coords_new(indd,1))/2, (coords_new(indd+1,2)+coords_new(indd,2))/2];             
           end
           
           %obtaining row and column data
           rowdata = (size(image,1)/2) - floor(midpoints(:,1));
           columndata = (size(image,2)/2) + ceil(midpoints(:,2));
           
           
           for indd = 1:size(rowdata,1)
              
               %to ensure there is no 0 index 
               if(rowdata(indd) == 0)
                   rowdata(indd) = 1;
               end
               if(columndata(indd) == 0)
                   columndata(indd) = 1;
               end
               
           end
           
           %calculating the projection
           sum = 0;
           for indd = 1:size(midpoints,1)
                
               sum = sum + dists(indd)*image(rowdata(indd),columndata(indd));
           
           end           
           projection(j) = sum;
    
            
        end
        
        projection_data(i,:) = projection;
        
    end
    
    %if there is odd number of pixels, we simply divide the projeciton data
    %by 2
    if(oddnumberpixel)
        projection_data = projection_data/2;
    end


end

        
        function [image] = back_projection(app,projected_data,image_size,filter_type)
%%% This function takes projected data, image size as [N,M] and filter type
%%% and gives the image output. The filter type options are 'none',
%%% 'triangle','sin','hamming','hann','blackman', 'flattopwin', 'ramp', 'ram-lak'.


%noise = randn(size(projected_data));
%projected_data = projected_data + sqrt(max(max(projected_data)))*noise;


[num_of_samples,num_of_angles] = size(projected_data);

image_max_size = max(image_size);
if(mod(image_max_size,2) == 1)
    
    image_max_size = image_max_size +1;
    
end

image_size = [image_max_size, image_max_size];

image = zeros(image_size(1),image_size(2));


%% defining filter type

new_data = zeros(num_of_samples,num_of_angles);

if(filter_type == "triangle")
    fft_data = fft2(projected_data);
    filter = triang(num_of_samples);
    for ind = 1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
end

if(filter_type == "sin")
    fft_data = fft2(projected_data);
    n = 1:num_of_samples;
    n = n';
    filter = sin(n);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
end

if(filter_type == "hamming")
    fft_data = fft2(projected_data);
    filter = hamming(num_of_samples);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
end

if(filter_type == "hann")
    fft_data = fft2(projected_data);
    filter = hann(num_of_samples);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
end

if(filter_type == "blackman")
    fft_data = fft2(projected_data);
    filter = blackman(num_of_samples);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
end

if(filter_type == "flattopwin")
    fft_data = fft2(projected_data);
    filter = flattopwin(num_of_samples);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
end

if(filter_type == "none")
    
end

if(filter_type == "ram-lak")
    
    fft_data = fft2(projected_data);
    n = 1:num_of_samples/2;
    n = n';
    filter = zeros(num_of_samples,1);
    n = flip(n);
    filter(1:num_of_samples/2) = n;
    n = flip(n);
    filter(num_of_samples/2+1:num_of_samples) = n;
    filter = filter/max(filter);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
       
end

if(filter_type == "ramp")
    
    fft_data = fft2(projected_data);
    n = 1:num_of_samples;
    n = n';
    filter = n/max(n);
    for ind=1:size(fft_data,2)
        new_data(:,ind) = filter.*fft_data(:,ind);
    end
    
    projected_data = ifft2(new_data);
       
end

%% back projection

M = max(image_size);
t = linspace(-M*sqrt(2)/2, M*sqrt(2)/2, num_of_samples);

x_val = [-image_size(1)/2:image_size(1)/2]';
y_val = [-image_size(2)/2:image_size(2)/2]';

angles = linspace(0,180-180/num_of_angles,num_of_angles);

for i = 1:num_of_angles
    
    theta = angles(i);
    image_a = zeros(image_size(1),image_size(2));
    
    for j = 1:num_of_samples
    
        corr_x_val = (t(j)-y_val*cosd(theta))/sind(theta);
        corr_y_val = (t(j)-x_val*sind(theta))/cosd(theta);
        
        %concatanate the x and y values
       coords = [x_val corr_y_val ; corr_x_val y_val];
       count = 1;
       coords_new = zeros(size(coords,1),2);

       %remove outside points
       for ind = 1:size(coords,1)
        if(abs(coords(ind,1))<=image_size(1)/2 && abs(coords(ind,2))<=image_size(2)/2)
            coords_new(count,:) = [coords(ind,1) coords(ind,2)];
            count = count +1;
        end
       end
       coords_new = coords_new(1:count-1,:);

       coords_new = sortrows(coords_new,1); 
       
       %finding the distances
       dists = zeros(size(coords_new,1)-1,1);
       for indd = 1:size(dists,1)            
           dists(indd) = sqrt((coords_new(indd+1,1)-coords_new(indd,1))^2+(coords_new(indd+1,2)-coords_new(indd,2))^2);             
       end
       
       %finding the midpoints
       midpoints = zeros(size(coords_new,1)-1,2);   
       for indd = 1:size(midpoints,1)           
           midpoints(indd,:) = [(coords_new(indd+1,1)+coords_new(indd,1))/2, (coords_new(indd+1,2)+coords_new(indd,2))/2];             
       end

       %obtaining row and column data
       rowdata = (image_size(1)/2) - floor(midpoints(:,1));
       columndata = (image_size(2)/2) + ceil(midpoints(:,2));

       for indd = 1:size(rowdata,1)
              
           %to ensure there is no 0 index 
           if(rowdata(indd) == 0)
               rowdata(indd) = 1;
           end
           if(columndata(indd) == 0)
               columndata(indd) = 1;
           end

       end
       
       %calculating the projection
       for index = 1:length(rowdata)

           image_a(rowdata(index),columndata(index)) = image_a(rowdata(index),columndata(index)) + dists(index)*projected_data(j,i);
          
       end
                

    end
    
    image = image + image_a;

end

image = abs(image);


end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BrowseButtonPushed(app, event)
 
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
            disp(path);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            image_name = uigetfile({'*.mat';'*.png';'*.jpg'},'Select Image File');
            
            if image_name == 0
                % User clicked the Cancel button.
                return;
            end
            
            if(image_name(end-2:end) == 'mat')
                image_sturct = struct2cell(load(image_name));
                app.image_data = cell2mat(image_sturct);
            else
                image_rgb = imread(image_name);
                app.image_data = double(im2gray(image_rgb));
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file,path] = uiputfile('projected_data.mat','Save file name');
            data = app.project_data;
            if file == 0
                % User clicked the Cancel button.
                return;
            end
        fullFileName = fullfile(path, file);
        save(fullFileName,"data");
            
            
            %save('projected_data.mat',app.project_data,'-mat');
        end

        % Button pushed function: CalculateProjectionButton
        function CalculateProjectionButtonPushed(app, event)
             image = app.image_data;
             numberofprojection = app.NumberofProjectionsEditField.Value;
             num_sampling_points = app.NumberofAnlgesEditField.Value;
             
             app.project_data = projection(app,image, num_sampling_points,numberofprojection);
             
             
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            image_data_plot = app.image_data;
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            
            I = imshow(image_data_plot,[],'parent',app.UIAxes);
            
            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)


            data = app.project_data;
            
            if(app.NumberofProjectionsEditField.Value == 0 || app.NumberofAnlgesEditField.Value == 0)
                [app.NumberofAnlgesEditField.Value,app.NumberofProjectionsEditField.Value] = size(data);
            end
            
            x_axis = linspace(1,app.NumberofProjectionsEditField.Value,app.NumberofProjectionsEditField.Value);
            
            h = animatedline(app.UIAxes_2);
           
            for k = 1:app.NumberofAnlgesEditField.Value
                clearpoints(h)
                addpoints(h,x_axis,data(k,:));
                ylim(app.UIAxes_2,[0,max(max(data))]);
                drawnow
                pause(0.1);
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            [file,path] = uiputfile('bprojected_data.png','Save file name');
            data = app.bproject_data;
            if file == 0
                % User clicked the Cancel button.
                return;
            end
        fullFileName = fullfile(path, file);
        imwrite(data,fullFileName);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            image_name = uigetfile({'*.mat'},'Select Projection File');
            
            if image_name == 0
                % User clicked the Cancel button.
                return;
            end
            
            image_sturct = struct2cell(load(image_name));
            app.project_data = cell2mat(image_sturct);
        end

        % Button pushed function: CalculateBackProjectionButton
        function CalculateBackProjectionButtonPushed(app, event)
            projected_data = app.project_data;
            
            if(app.NumberofProjectionsEditField.Value == 0 || app.NumberofAnlgesEditField.Value == 0)
                [app.NumberofAnlgesEditField.Value,app.NumberofProjectionsEditField.Value] = size(projected_data);
            end
            
            
            image_size = app.ImageSizeEditField.Value;
            filter_type = app.FilterTypeDropDown.Value;
            
            projected_data = projected_data';
            
            app.image_data = back_projection(app,projected_data,image_size,filter_type);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Animation')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'Projection Data')
            zlabel(app.UIAxes_2, 'Z')
            app.EE415TermProjectLabel = uilabel(app.UIFigure);
            app.EE415TermProjectLabel.Text = 'EE415 Term Project';
            app.MustafaErdiKararmaz2375194Label = uilabel(app.UIFigure);
            app.MustafaErdiKararmaz2375194Label.Text = 'Mustafa Erdi Kararmaz 2375194';
            app.ProjectionPanel = uipanel(app.UIFigure);
            app.ProjectionPanel.Title = 'Projection';
            app.LoadButton = uibutton(app.ProjectionPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.NumberofProjectionsEditFieldLabel = uilabel(app.ProjectionPanel);
            app.NumberofProjectionsEditFieldLabel.Text = 'Number of Projections';
            app.NumberofProjectionsEditField = uieditfield(app.ProjectionPanel, 'numeric');
            app.NumberofAnlgesEditFieldLabel = uilabel(app.ProjectionPanel);
            app.NumberofAnlgesEditFieldLabel.Text = 'Number of Anlges';
            app.NumberofAnlgesEditField = uieditfield(app.ProjectionPanel, 'numeric');
            app.CalculateProjectionButton = uibutton(app.ProjectionPanel, 'push');
            app.CalculateProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateProjectionButtonPushed, true);
            app.CalculateProjectionButton.Text = 'Calculate Projection';
            app.SaveButton = uibutton(app.ProjectionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BackProjectionPanel = uipanel(app.UIFigure);
            app.BackProjectionPanel.Title = 'Back Projection';
            app.LoadButton_2 = uibutton(app.BackProjectionPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.SaveButton_2 = uibutton(app.BackProjectionPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.ImageSizeEditFieldLabel = uilabel(app.BackProjectionPanel);
            app.ImageSizeEditFieldLabel.Text = 'Image Size';
            app.ImageSizeEditField = uieditfield(app.BackProjectionPanel, 'numeric');
            app.FilterTypeDropDownLabel = uilabel(app.BackProjectionPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.BackProjectionPanel);
            app.FilterTypeDropDown.Items = {'none', 'triangle', 'sin', 'hamming', 'hann', 'blackman', 'flattopwin', 'ram-lak', 'ramp'};
            app.FilterTypeDropDown.Value = 'none';
            app.CalculateBackProjectionButton = uibutton(app.BackProjectionPanel, 'push');
            app.CalculateBackProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateBackProjectionButtonPushed, true);
            app.CalculateBackProjectionButton.Text = 'Calculate Back-Projection';
            app.AnimationButton = uibutton(app.UIFigure, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Animation';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ee415_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end